classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AES_Encryption          matlab.ui.Figure
        AESENCRYPTIONLabel      matlab.ui.control.Label
        DataTextAreaLabel       matlab.ui.control.Label
        DataTextArea            matlab.ui.control.TextArea
        KeyTextAreaLabel        matlab.ui.control.Label
        KeyTextArea             matlab.ui.control.TextArea
        AESModeDropDownLabel    matlab.ui.control.Label
        AESModeDropDown         matlab.ui.control.DropDown
        AESTypeDropDownLabel    matlab.ui.control.Label
        AESTypeDropDown         matlab.ui.control.DropDown
        EncryptButton           matlab.ui.control.Button
        DecryptButton           matlab.ui.control.Button
        KeyformatDropDownLabel  matlab.ui.control.Label
        KeyformatDropDown       matlab.ui.control.DropDown
        IVTextAreaLabel         matlab.ui.control.Label
        IVTextArea              matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EncryptButton
        function EncryptButtonPushed(app, event)
            userData = app.DataTextArea.Value;
            keyInput = app.KeyTextArea.Value;
            keyType = app.KeyformatDropDown.Value;
            AESMode = app.AESModeDropDown.Value;
            AESType = app.AESTypeDropDown.Value;
            IVValue = app.IVTextArea.Value;
            % Raise error dialog box if entered key is invalid
            if key_check(keyInput, keyType, AESMode) || IV_check(IVValue)
                if key_check(keyInput, keyType, AESMode)
                    msgbox(["    Invalid entered key. Check entered key";
                            "and whether the correct key format is selected."], "Invalid key");
                end
                if IV_check(IVValue)
                    msgbox(["                 Invalid entered IV. ";
                            "Format is 16 hex value, check entered value "], "Invalid IV");
                end
                if data_check(userData, keyType)
                    msgbox(["         Invalid entered data.";
                            "Encryption data requires an input" ], "Invalid data");
                end
            else
                cipher = main_encrypt(userData, keyInput, keyType, AESMode, AESType, IVValue);
                app.DataTextArea.Value = cipher;
            end
        end

        % Button pushed function: DecryptButton
        function DecryptButtonPushed(app, event)
            userData = app.DataTextArea.Value;
            keyInput = app.KeyTextArea.Value;
            keyType = app.KeyformatDropDown.Value;
            AESMode = app.AESModeDropDown.Value;
            AESType = app.AESTypeDropDown.Value;
            IVValue = app.IVTextArea.Value;
            % Raise error dialog box if either key, data or IV scripts rais flags
            if key_check(keyInput, keyType, AESMode) || IV_check(IVValue) || data_check(userData, keyType)
                if key_check(keyInput, keyType, AESMode)
                    msgbox(["    Invalid entered key. Check entered key";
                            "and whether the correct key format is selected."], "Invalid key");
                end
                if IV_check(IVValue)
                    msgbox(["                 Invalid entered IV. ";
                            "Format is 16 hex value, check entered value "], "Invalid IV");
                end
                if data_check(userData, keyType)
                    msgbox(["        Invalid entered data. Decryption format is a";
                            " continuous hex value of length that is a multiple of 16" ], "Invalid data");
                end
            else
                plainttext = main_decrypt(userData, keyInput, keyType, AESMode, AESType, IVValue);
                app.DataTextArea.Value = plainttext;
            end
        end

        % Value changed function: KeyTextArea
        function KeyTextAreaValueChanged(app, event)

        end

        % Value changed function: AESTypeDropDown
        function AESTypeDropDownValueChanged(app, event)

            if app.AESTypeDropDown.Value == "CBC"
                % Reveal/enable IV text and label if AES Type is CBC
                app.IVTextArea.Enable = 1;
                app.IVTextAreaLabel.Enable = 1;
            else
                % Hide/disable IV text and label if AES Type is ECB
                app.IVTextArea.Enable = 0;
                app.IVTextAreaLabel.Enable = 0;
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AES_Encryption = uifigure('Visible', 'off');
            app.AES_Encryption.Name = 'AES Encryption';
            app.AESENCRYPTIONLabel = uilabel(app.AES_Encryption);
            app.AESENCRYPTIONLabel.Text = 'AES ENCRYPTION';
            app.DataTextAreaLabel = uilabel(app.AES_Encryption);
            app.DataTextAreaLabel.Text = 'Data';
            app.DataTextArea = uitextarea(app.AES_Encryption);
            app.DataTextArea.Tooltip = {'Decryption data has to be a continuous hex value of length that is a multiple of 16 values'};
            app.KeyTextAreaLabel = uilabel(app.AES_Encryption);
            app.KeyTextAreaLabel.Text = 'Key';
            app.KeyTextArea = uitextarea(app.AES_Encryption);
            app.KeyTextArea.ValueChangedFcn = createCallbackFcn(app, @KeyTextAreaValueChanged, true);
            app.KeyTextArea.Tooltip = {'Exmaples:'; ''; 'Plaintext:Thats my Kung Fu'; ''; 'Hex:546861747'; '3206D79204B7'; '56E67204675'};
            app.AESModeDropDownLabel = uilabel(app.AES_Encryption);
            app.AESModeDropDownLabel.Text = 'AES Mode';
            app.AESModeDropDown = uidropdown(app.AES_Encryption);
            app.AESModeDropDown.Items = {'128-bit', '192-bit', '256-bit'};
            app.AESModeDropDown.Value = '128-bit';
            app.AESTypeDropDownLabel = uilabel(app.AES_Encryption);
            app.AESTypeDropDownLabel.Text = 'AES Type';
            app.AESTypeDropDown = uidropdown(app.AES_Encryption);
            app.AESTypeDropDown.Items = {'EBC', 'CBC'};
            app.AESTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AESTypeDropDownValueChanged, true);
            app.AESTypeDropDown.Value = 'EBC';
            app.EncryptButton = uibutton(app.AES_Encryption, 'push');
            app.EncryptButton.ButtonPushedFcn = createCallbackFcn(app, @EncryptButtonPushed, true);
            app.EncryptButton.Text = 'Encrypt';
            app.DecryptButton = uibutton(app.AES_Encryption, 'push');
            app.DecryptButton.ButtonPushedFcn = createCallbackFcn(app, @DecryptButtonPushed, true);
            app.DecryptButton.Text = 'Decrypt';
            app.KeyformatDropDownLabel = uilabel(app.AES_Encryption);
            app.KeyformatDropDownLabel.Text = 'Key format';
            app.KeyformatDropDown = uidropdown(app.AES_Encryption);
            app.KeyformatDropDown.Items = {'Plaintext', 'Hex'};
            app.KeyformatDropDown.Value = 'Plaintext';
            app.IVTextAreaLabel = uilabel(app.AES_Encryption);
            app.IVTextAreaLabel.Enable = 'off';
            app.IVTextAreaLabel.Text = 'IV';
            app.IVTextArea = uitextarea(app.AES_Encryption);
            app.IVTextArea.Enable = 'off';
            app.IVTextArea.Tooltip = {'Exmaple:'; '347117a91ddf95ab54037d299e32face'; ''; 'Leave empty if unused'};
            % Show the figure after all components are created
            app.AES_Encryption.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AES_Encryption)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AES_Encryption)
        end
    end
end